classdef StarIDGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StarIDUIFigure        matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        ResetOutputButton     matlab.ui.control.Button
        SaveOutputButton      matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        StopButton            matlab.ui.control.Button
        GetIMGContinueButton  matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        DisconnectButton      matlab.ui.control.Button
        GetImageButton        matlab.ui.control.Button
        StarIDLamp            matlab.ui.control.Lamp
        StarIDLampLabel       matlab.ui.control.Label
        ConnectLamp           matlab.ui.control.Lamp
        ConnectLampLabel      matlab.ui.control.Label
        LogTextArea           matlab.ui.control.TextArea
        SaveLogButton         matlab.ui.control.Button
        StartStarIDButton     matlab.ui.control.Button
        ServerConnectButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        timer1;
    end
    
    properties (Access = public)
        client % Description
        Params = struct;
        StarMat;
        pixX;
        pixY;
        FoVx;
        FoVy;
        ThresholdMag;
        Threshold = 20;
        UserMem;
        MemResetVal;
        % 파일 아웃풋용
        SuccessID = [];
        Quaternion = [];
        Attitude = [];
    end
    
    methods (Access = private)
        
        function TCPIP_writeData(app)
            %   보낼 데이터를 write 한다.            
            % 이전에 남은 데이터는 비우고싶은데 작동하지 않는것 같음.
            flush(app.client);            
            write(app.client, 1, 'uint8');           
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes);
            [user,~] = memory;
            app.MemResetVal = user.MemAvailableAllArrays - user.MemAvailableAllArrays/3;
            % 앱 킬 때 소스들의 경로 추가
            if isdeployed
                
            else
                oldpath = addpath(genpath('../'));
            end
        end

        % Button pushed function: ServerConnectButton
        function ServerConnectButtonPushed(app, event)
            % TCP/IP 서버와 연결 
            if isa(app.client,'tcpclient') == 0 || size(app.client,1) == 0 
                app.client = tcpclient('localhost', 10000); 
%                 configureCallback(app.client,'terminator',@TCPIP_TerminatorCallbackFnc);
            end
            lamptemp = isa(app.client,'tcpclient');
            if lamptemp == 1 && size(app.client,1) ~= 0
                app.ConnectLamp.Color = [0 1 0];
            end
        end

        % Button pushed function: StartStarIDButton
        function StartStarIDButtonPushed(app, event)
            % 받아온 이미지로 별 인식 실행
            if isa(app.client,'tcpclient') == 1
                [SvectorFlag, PyFlag, Attitude, AttiQuaternion] = main_StarID(app.StarMat, app.Threshold, app.UIAxes, app.Params);
                app.LogTextArea.Value = ShowLog(SvectorFlag);
                if PyFlag
                    app.TextArea.Value = ShowCurrentStatus(Attitude);
                    app.StarIDLamp.Color = [0 1 0];
                    app.TextArea.FontColor = [1 0 0];
                else
                    app.StarIDLamp.Color = [1 0 0];
                    app.TextArea.FontColor = [0 0 0];
                end
                AttitudeArr = [Attitude.RA, Attitude.DEC, Attitude.ROT];
                app.SuccessID = [app.SuccessID; PyFlag];        
                app.Attitude = [app.Attitude; AttitudeArr];
                app.Quaternion = [app.Quaternion; AttiQuaternion];
            end
        end

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            % TCP/IP 클라이언트가 열려있고, 받을 수 있는 데이터가 0보다 크면 실행.
            if isa(app.client,'tcpclient') == 1 && app.client.NumBytesAvailable > 0 && size(app.client,1) ~= 0
                [app.UserMem,~] = memory;
                MemReset = app.UserMem.MemAvailableAllArrays < app.MemResetVal;
                if MemReset
                    cla(app.UIAxes,'reset');
                end
                [app.StarMat, app.pixX, app.pixY, app.FoVx, app.FoVy,app.ThresholdMag] = TCPIPReadData(app.client);
                
                
                % 통신을 통해 들어온 픽셀수 FOV 값을 main에 넘기기 위해 스트럭쳐에집어넣음.
                app.Params.pixX = app.pixX;
                app.Params.pixY = app.pixY;
                app.Params.FoVx = app.FoVx;
                app.Params.FoVy = app.FoVy;
                app.Params.ThresholdMag = app.ThresholdMag;

                I = imshow(app.StarMat, 'parent', app.UIAxes);
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
           end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if isa(app.client,'tcpclient') == 1
                delete(app.client);
                app.client = [];
            end
            lamptemp = isa(app.client,'tcpclient');
            if size(app.client,1) == 0
                app.ConnectLamp.Color = [1 0 0];
            end
        end

        % Button pushed function: GetIMGContinueButton
        function GetIMGContinueButtonPushed(app, event)
            % TCP/IP 클라이언트가 열려있고, 받을 수 있는 데이터가 0보다 크면 실행.
            if isa(app.client,'tcpclient') == 1 && app.client.NumBytesAvailable > 0 && size(app.client,1) ~= 0
            app.timer1 = timer("BusyMode","drop","ExecutionMode","fixedSpacing","Period",0.25);
            app.timer1.StartFcn = @(server,evt)GetImageContinue(server,evt,app);
            app.timer1.TimerFcn = @(server,evt)GetImageContinue(server,evt,app);
            start(app.timer1);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            delete(app.timer1);            
        end

        % Close request function: StarIDUIFigure
        function StarIDUIFigureCloseRequest(app, event)
            delete(app.timer1);
            delete(app)            
        end

        % Button pushed function: SaveOutputButton
        function SaveOutputButtonPushed(app, event)
            WriteOutput(app);
        end

        % Button pushed function: ResetOutputButton
        function ResetOutputButtonPushed(app, event)
            app.SuccessID = [];
            app.Quaternion = [];
            app.Attitude = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StarIDUIFigure = uifigure('Visible', 'off');
            app.StarIDUIFigure.Name = 'StarID';
            app.StarIDUIFigure.Icon = 'Icon2.png';
            app.StarIDUIFigure.CloseRequestFcn = createCallbackFcn(app, @StarIDUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.StarIDUIFigure);
            app.ServerConnectButton = uibutton(app.GridLayout, 'push');
            app.ServerConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ServerConnectButtonPushed, true);
            app.ServerConnectButton.Text = 'ServerConnect';
            app.StartStarIDButton = uibutton(app.GridLayout, 'push');
            app.StartStarIDButton.ButtonPushedFcn = createCallbackFcn(app, @StartStarIDButtonPushed, true);
            app.StartStarIDButton.Text = 'Start StarID';
            app.SaveLogButton = uibutton(app.GridLayout, 'push');
            app.SaveLogButton.Text = 'Save Log';
            app.LogTextArea = uitextarea(app.GridLayout);
            app.LogTextArea.Editable = 'off';
            app.ConnectLampLabel = uilabel(app.GridLayout);
            app.ConnectLampLabel.Text = 'Connect';
            app.ConnectLamp = uilamp(app.GridLayout);
            app.StarIDLampLabel = uilabel(app.GridLayout);
            app.StarIDLampLabel.Text = 'StarID';
            app.StarIDLamp = uilamp(app.GridLayout);
            app.GetImageButton = uibutton(app.GridLayout, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'GetImage';
            app.DisconnectButton = uibutton(app.GridLayout, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.Panel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.GetIMGContinueButton = uibutton(app.GridLayout, 'push');
            app.GetIMGContinueButton.ButtonPushedFcn = createCallbackFcn(app, @GetIMGContinueButtonPushed, true);
            app.GetIMGContinueButton.Text = 'GetIMGContinue';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Status'};
            app.SaveOutputButton = uibutton(app.GridLayout, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
            app.SaveOutputButton.Text = 'SaveOutput';
            app.ResetOutputButton = uibutton(app.GridLayout, 'push');
            app.ResetOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ResetOutputButtonPushed, true);
            app.ResetOutputButton.Text = 'ResetOutput';
            % Show the figure after all components are created
            app.StarIDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StarIDGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StarIDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StarIDUIFigure)
        end
    end
end