classdef StarmapSimGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StarmapSIMUIFigure           matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        PixYEditField                matlab.ui.control.NumericEditField
        PixXxPixYEditField           matlab.ui.control.NumericEditField
        PixXxPixYEditFieldLabel      matlab.ui.control.Label
        ImageSizeSelectOption        matlab.ui.container.ButtonGroup
        TypeImageSize                matlab.ui.control.RadioButton
        SelectImageSize              matlab.ui.control.RadioButton
        ServerText                   matlab.ui.control.TextArea
        ConnectLamp                  matlab.ui.control.Lamp
        ServerLamp                   matlab.ui.control.Lamp
        ServerONButton               matlab.ui.control.Button
        Slider                       matlab.ui.control.Slider
        MagLimitEditFieldLabel       matlab.ui.control.Label
        MagLimitEditField            matlab.ui.control.NumericEditField
        FoVyEditFieldLabel           matlab.ui.control.Label
        FoVyEditField                matlab.ui.control.NumericEditField
        FoVxEditFieldLabel           matlab.ui.control.Label
        FoVxEditField                matlab.ui.control.NumericEditField
        ROTEditFieldLabel            matlab.ui.control.Label
        ROTEditField                 matlab.ui.control.NumericEditField
        DECEditFieldLabel            matlab.ui.control.Label
        DECEditField                 matlab.ui.control.NumericEditField
        ImagesizeDropDownLabel       matlab.ui.control.Label
        ImagesizeDropDown            matlab.ui.control.DropDown
        SavedirEditFieldLabel        matlab.ui.control.Label
        SavedirEditField             matlab.ui.control.EditField
        RAEditFieldLabel             matlab.ui.control.Label
        RAEditField                  matlab.ui.control.NumericEditField
        SaveimgButton                matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        BlurTab                      matlab.ui.container.Tab
        K3EditFieldLabel             matlab.ui.control.Label
        K3EditField                  matlab.ui.control.NumericEditField
        K2EditFieldLabel             matlab.ui.control.Label
        K2EditField                  matlab.ui.control.NumericEditField
        K1EditFieldLabel             matlab.ui.control.Label
        K1EditField                  matlab.ui.control.NumericEditField
        SigmaEditFieldLabel          matlab.ui.control.Label
        SigmaEditField               matlab.ui.control.NumericEditField
        TextArea_2                   matlab.ui.control.TextArea
        ButtonGroup_Blur             matlab.ui.container.ButtonGroup
        DisableButton_Blur           matlab.ui.control.RadioButton
        EnableButton_Blur            matlab.ui.control.RadioButton
        TextArea_5                   matlab.ui.control.TextArea
        BackNoiseTab                 matlab.ui.container.Tab
        ButtonGroup_Noise            matlab.ui.container.ButtonGroup
        DisableButton_Noise          matlab.ui.control.RadioButton
        EnableButton_Noise           matlab.ui.control.RadioButton
        TextArea                     matlab.ui.control.TextArea
        GmaxEditField                matlab.ui.control.NumericEditField
        GmaxEditFieldLabel           matlab.ui.control.Label
        GminEditField                matlab.ui.control.NumericEditField
        GminEditFieldLabel           matlab.ui.control.Label
        MissingStarTab               matlab.ui.container.Tab
        MissingNumMaxEditField       matlab.ui.control.NumericEditField
        MissingNumMaxEditFieldLabel  matlab.ui.control.Label
        MissingNumMinEditField       matlab.ui.control.NumericEditField
        MissingNumMinEditFieldLabel  matlab.ui.control.Label
        ButtonGroup_Missing          matlab.ui.container.ButtonGroup
        DisableButton_Missing        matlab.ui.control.RadioButton
        EnableButton_Missing         matlab.ui.control.RadioButton
        TextArea_4                   matlab.ui.control.TextArea
        RandomObjTab                 matlab.ui.container.Tab
        ObjNumMaxEditField           matlab.ui.control.NumericEditField
        ObjNumMaxEditField_2Label    matlab.ui.control.Label
        ObjRadiusMaxEditField        matlab.ui.control.NumericEditField
        ObjRadiusMaxEditFieldLabel   matlab.ui.control.Label
        ObjRadiusMinEditField        matlab.ui.control.NumericEditField
        ObjRadiusMinEditFieldLabel   matlab.ui.control.Label
        ObjNumMinEditField           matlab.ui.control.NumericEditField
        ObjNumMinEditFieldLabel      matlab.ui.control.Label
        ButtonGroup_RandObj          matlab.ui.container.ButtonGroup
        DisableButton_RandObj        matlab.ui.control.RadioButton
        EnableButton_RandObj         matlab.ui.control.RadioButton
        TextArea_3                   matlab.ui.control.TextArea
        TrackingTab                  matlab.ui.container.Tab
        DirEditField                 matlab.ui.control.EditField
        DirEditFieldLabel            matlab.ui.control.Label
        EnableCheckBox               matlab.ui.control.CheckBox
        TextArea_6                   matlab.ui.control.TextArea
        StopButton                   matlab.ui.control.Button
        TrackingRunButton            matlab.ui.control.Button
        ReadIMGTab                   matlab.ui.container.Tab
        ReadIMGButton                matlab.ui.control.Button
        EnableCheckBox_2             matlab.ui.control.CheckBox
        TextArea_7                   matlab.ui.control.TextArea
        DirEditField_2               matlab.ui.control.EditField
        DirEditField_2Label          matlab.ui.control.Label
        CenterPanel                  matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        StatusTextAreaLabel          matlab.ui.control.Label
        StatusTextArea               matlab.ui.control.TextArea
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        LogTextAreaLabel             matlab.ui.control.Label
        LogTextArea                  matlab.ui.control.TextArea
        SaveLogButton                matlab.ui.control.Button
        ClearLogButton               matlab.ui.control.Button
        Menu                         matlab.ui.container.Menu
        Menu_2                       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        % 파라미터 스트럭쳐
        Params;
        % 기본 픽셀 갯수
        pixX = 1024;
        pixY = 1024;        
        % 별 이미지가 들어가는 변수
        StarImage = 0;
        % 이미지 저장 기본 경로
        SaveImagePath = './';
        % 이미지를 만들면 1이 되는 Flag
        StarImageFlag = 0;
        % TCP/IP 서버
        server;
        % TCP/IP 서버로 보내는 변수 중 그림 담당.
        SendMatrix;        
        % Tracking mode 의 while 문 flag
        TrackingFlag = 0;
        % 기본 카메라 모델
        Camera = 1;
        % 외부 IMG 경로
        IMGPath = "";
        %Tracking File 경로
        TrackingPath = "";
    end

    properties (Access = public)

        Property11 % Description
    end

    methods (Access = private)
        % Params 스트럭쳐에 변수들을 넣는 함수
        function [Params] = SetParam(app)
            Params.RA = app.RAEditField.Value;
            Params.DEC = app.DECEditField.Value;
            Params.ROT = app.ROTEditField.Value;
            Params.FoVx = app.FoVxEditField.Value;
            Params.FoVy = app.FoVyEditField.Value;
            Params.Mag = app.MagLimitEditField.Value;
            Params.PixelNum_X = app.pixX;
            Params.PixelNum_Y = app.pixY;
            Params.sigma = app.SigmaEditField.Value;
            Params.K1 = app.K1EditField.Value;
            Params.K2 = app.K2EditField.Value;
            Params.K3 = app.K3EditField.Value;
            Params.ActiveBlur = app.EnableButton_Blur.Value;
            Params.MaxNoise = app.GmaxEditField.Value;
            Params.MinNoise = app.GminEditField.Value;
            Params.ActiveNoise = app.EnableButton_Noise.Value;
            Params.MissingMax = app.MissingNumMaxEditField.Value;
            Params.MissingMin = app.MissingNumMinEditField.Value;
            Params.ActiveMissing = app.EnableButton_Missing.Value;
            Params.MissingNum = NaN;
            Params.RandObjMaxNum = app.ObjNumMaxEditField.Value;
            Params.RandObjMinNum = app.ObjNumMinEditField.Value;
            Params.RandObjMaxRadi = app.ObjRadiusMaxEditField.Value;
            Params.RandObjMinRadi = app.ObjRadiusMinEditField.Value;
            Params.ActiveRandObj = app.EnableButton_RandObj.Value;
            Params.RandObjNum = NaN;
        end
        
        function LimitControl(app)
            % GUI 상 Limit 이 필요한 변수들의 Limit Control 함수
             if app.GmaxEditField.Value < app.GminEditField.Value
                app.GmaxEditField.Value = app.GminEditField.Value;
             end
             if app.MissingNumMinEditField.Value > app.MissingNumMaxEditField.Value
                 app.MissingNumMaxEditField.Value = app.MissingNumMinEditField.Value;
             end            
             if app.ObjNumMaxEditField.Value < app.ObjNumMinEditField.Value
                 app.ObjNumMaxEditField.Value = app.ObjNumMinEditField.Value;
             end
             if app.ObjRadiusMaxEditField.Value < app.ObjRadiusMinEditField.Value
                 app.ObjRadiusMaxEditField.Value = app.ObjRadiusMinEditField.Value;
             end
        end


        function TCPIP_writeData(app)
            %TCPIP_writeData
            %   TCP/IP로 보낼 데이터를 write 한다.
%             if app.server.
            % 이전에 남은 데이터는 비우고싶은데 작동하지 않는것 같음.
            flush(app.server);
            % write 는 1차원 배열로만 되기때문에 모양을 1차원 배열로 바꾸기
            app.SendMatrix = reshape(app.StarImage, [1,app.pixX * app.pixY]);
            % 픽셀 갯수 정보
            write(app.server,app.pixX,'uint16');
            write(app.server,app.pixY,'uint16');
            % FoV 정보
            write(app.server,app.FoVxEditField.Value,'single');
            write(app.server,app.FoVyEditField.Value,'single');
            % 별 밝기등급 제한 정보
            write(app.server,app.MagLimitEditField.Value,'single');
            % 별 이미지 매트릭스 정보
            write(app.server,app.SendMatrix,'uint8');
        end
        
        function results = TCPIP_readData(app)
            results = 0;
            while(app.server.NumBytesAvailable > 0)
                temp = read(app.server, 1, "uint8");
                results = temp(1);
            end
            
        end
        
        
        function TCPIP_writeData2(app, IMG)
            %TCPIP_writeData2
            %   TCP/IP로 보낼 데이터를 write 한다.
            % 이전에 남은 데이터는 비우고싶은데 작동하지 않는것 같음.
            flush(app.server);
            % write 는 1차원 배열로만 되기때문에 모양을 1차원 배열로 바꾸기
            app.SendMatrix = reshape(IMG, [1,app.pixX * app.pixY]);
            % 픽셀 갯수 정보
            write(app.server,app.pixX,'uint16');
            write(app.server,app.pixY,'uint16');
            % FoV 정보
            write(app.server,app.FoVxEditField.Value,'single');
            write(app.server,app.FoVyEditField.Value,'single');
            % 별 밝기등급 제한 정보
            write(app.server,app.MagLimitEditField.Value,'single');
            % 별 이미지 매트릭스 정보
            write(app.server,app.SendMatrix,'uint8');
        end
    end
    
    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes);
            % 앱 킬 때 소스들의 경로 추가
            if isdeployed
                
            else
                oldpath = addpath(genpath('../'));
            end
        end

        % Callback function: DECEditField, MagLimitEditField, 
        % RAEditField, ROTEditField, RunButton, Slider
        function RunButtonPushed(app, event)
%             tic
            %Parameter Limit Control
            LimitControl(app);            
            % Set Parameters
            app.Params = SetParam(app);
            % 이미지 생성 메인문
            [app.StarImage] = main(app.Params);
            % GUI상의 Status 창에 텍스트 표시 
            app.StatusTextArea.Value = ShowStatus(app.Params);  
            % cla 리셋을 해 줌으로써 플롯의 위치가 틀어지지 않는다.
            % 코드가 굉장히 느려지는 현상이 있어서 사용안함.
%             cla(app.UIAxes,'reset');
            % GUI에 만든 이미지 보여주기
            I = imshow(app.StarImage, 'parent', app.UIAxes);
%             I = imshow(app.StarImage, 'parent', app.UIAxes,...
%                 'XData', [1 app.UIAxes.Position(3)], ...
%                 'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            app.StarImageFlag = 1;              
% =========================================================================
% TCP/IP 서버가 열려있고, client 와 연결되어 있으면, 보낼 데이터를 write 한다.
            if isa(app.server,'tcpserver.internal.TCPServer') == 1
                if (app.server.Connected)
                    % 보낼 데이터 쓰는 함수.
                    TCPIP_writeData(app);                     
                end
            end
%             toc
        end

        % Value changed function: ImagesizeDropDown
        function ImagesizeDropDownValueChanged(app, event)
            value = app.ImagesizeDropDown.Value;
            if app.SelectImageSize.Value == true
                if(value == '1024x1024 pix')
                    app.pixX = 1024;
                    app.pixY = 1024;
                elseif(value == '2048x2048 pix')
                    app.pixX = 2048;
                    app.pixY = 2048;
                elseif(value == '4096x4096 pix')
                    app.pixX = 4096;
                    app.pixY = 4096;
                elseif(value == ' 512x 512 pix')% 한칸 빈칸 주의
                    app.pixX = 512;
                    app.pixY = 512;
                end
            end
        end

        % Key press function: StarmapSIMUIFigure
        function StarmapSIMUIFigureKeyPress(app, event)
            % 키 입력 기능 함수
            key = event.Key;
            if isequal(event.Modifier,'shift')
                switch key
%                     case 'f1'
%                         app.ChangeData(app);
%                         app.Message.Value = key;
                end
            elseif isequal(event.Modifier,'control')
                switch key
%                     case 'q'
%                         app.Message.Value = 'quitting'; % write to text area of GUI
                end
            else
                switch key
                    case 'return'
                        RunButtonPushed(app,event);
%                 case 'f1'
%                     app.ChangeData(app);
%                     app.Message.Value = key;
%                 case 'shift'
%                     app.ChangeColor(app);
%                     app.Message.Value = key;
                end
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ROTEditField.Value = changingValue;
        end

        % Button pushed function: SaveimgButton
        function SaveimgButtonPushed(app, event)
            if (app.StarImageFlag)
                SaveFiles.Images(app.SaveImagePath,app.StarImage);
            else
                app.StatusTextArea.Value = {'No Image'};
            end
        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonPushed(app, event)

        end

        % Selection changed function: ButtonGroup_Blur
        function ButtonGroup_BlurSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_Blur.SelectedObject;

        end

        % Value changed function: SavedirEditField
        function SavedirEditFieldValueChanged(app, event)
            value = app.SavedirEditField.Value;
            app.SaveImagePath = value;
        end

        % Callback function
        function LogTextAreaValueChanged(app, event)
            value = app.LogTextArea.Value;

        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            app.LogTextArea.Value = '';

        end

        % Button pushed function: ServerONButton
        function ServerONButtonPushed(app, event)
            % TCP/IP 서버 실행
            if isa(app.server,'tcpserver.internal.TCPServer') == 0
                app.server = tcpserver("0.0.0.0", 10000, "ConnectionChangedFcn", @(server,evt)TCPIP_ConnectionChangedFcn(server,evt,app)); 
%                 configureCallback(app.server,'terminator',@TCPIP_Server_TerminatorCallbackFnc);
            end
            
            lamptemp = isa(app.server,'tcpserver.internal.TCPServer');
            tempstr = [strcat("ServerAdr: ",app.server.ServerAddress); 
            strcat("PortNum: ", num2str(app.server.ServerPort))];
            app.ServerText.Value = tempstr;

            if lamptemp == 1
                app.ServerLamp.Color = [0 1 0];
            else
                app.ServerLamp.Color = [1 0 0];
            end
            
        end

        % Callback function
        function GminEditFieldValueChanged(app, event)
            value = app.GminEditField.Value;    
        end

        % Callback function
        function GmaxEditFieldValueChanged(app, event)
            value = app.GmaxEditField.Value;
        end

        % Callback function
        function MissingNumMinEditFieldValueChanged(app, event)
            value = app.MissingNumMinEditField.Value;          
        end

        % Callback function
        function MissingNumMaxEditFieldValueChanged(app, event)
            value = app.MissingNumMaxEditField.Value;
        end

        % Value changed function: FoVxEditField, FoVyEditField, 
        % PixXxPixYEditField
        function PixXxPixYEditFieldValueChanged(app, event)
            value = app.PixXxPixYEditField.Value;
            app.PixYEditField.Value = round(app.FoVyEditField.Value * value / app.FoVxEditField.Value);
            if app.TypeImageSize.Value == true
                app.pixX = value;
                app.pixY = app.PixYEditField.Value;
            end
        end

        % Selection changed function: ImageSizeSelectOption
        function ImageSizeSelectOptionSelectionChanged(app, event)
            selectedButton = app.ImageSizeSelectOption.SelectedObject;
            ImagesizeDropDownValueChanged(app, event);
            if app.TypeImageSize.Value == true
                app.pixX = app.PixXxPixYEditField.Value;
                app.pixY = app.PixYEditField.Value;
            end 
        end

        % Button pushed function: TrackingRunButton
        function TrackingRunButtonPushed(app, event)
            if app.EnableCheckBox.Value == true
                % 버튼 비활성화
                app.RunButton.Enable = 'off';
                app.TrackingRunButton.Enable = 'off';
                app.RAEditField.Enable = 'off';
                app.DECEditField.Enable = 'off';
                app.ROTEditField.Enable = 'off';
                app.FoVxEditField.Enable = 'off';
                app.FoVyEditField.Enable = 'off';
                app.MagLimitEditField.Enable = 'off';
                app.Slider.Enable = 'off';
                
                % 버튼 활성화
                app.StopButton.Enable = 'on';
                
                app.TrackingFlag = 1;
                %Parameter Limit Control
                LimitControl(app);                          
                % Set Parameters
                app.Params = SetParam(app);
                
                % 파일을 읽어 중심좌표 읽어오기
                
                [SequenceFile] = ReadPosiFile(app.TrackingPath);
                
                i = 1;
                ReadytoSend = 1;
                
                while(app.TrackingFlag)
                    tic
                    
                    % 파일이 끝이면 종료
                    if size(SequenceFile,1) <= i
                        app.TrackingFlag = 0;
                        % 버튼 활성화
                        app.RunButton.Enable = 'on';
                        app.TrackingRunButton.Enable = 'on';
                        app.RAEditField.Enable = 'on';
                        app.DECEditField.Enable = 'on';
                        app.ROTEditField.Enable = 'on';
                        app.FoVxEditField.Enable = 'on';
                        app.FoVyEditField.Enable = 'on';
                        app.MagLimitEditField.Enable = 'on';
                        app.Slider.Enable = 'on';
                        % 버튼 비활성화
                        app.StopButton.Enable = 'off';
                    end
                    %좌표 파라미터 넣기
                    app.Params.RA = SequenceFile(i,2);
                    app.Params.DEC = SequenceFile(i,3);
                    app.Params.ROT = SequenceFile(i,4);
                    
                    % GUI상의 Status 창에 텍스트 표시 
                    app.StatusTextArea.Value = ShowStatus(app.Params);
                    % 이미지 생성 메인문                                                
                    [app.StarImage] = main(app.Params);   
                    % GUI상의 Status 창에 텍스트 표시 
                    app.StatusTextArea.Value = ShowStatus(app.Params);                     
                    % GUI에 만든 이미지 보여주기
                    I = imshow(app.StarImage, 'parent', app.UIAxes);     
                    % Set limits of axes
                    % 그림을 UIAxes 에 Fit 하게 함.
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)];
                    
                    %Figure 업데이트 및 콜백 처리
                    drawnow();
                    
                    app.StarImageFlag = 1; 
                    
                    % TCP/IP 서버가 열려있고, client 와 연결되어 있으면, 보낼 데이터를 write 한다.
                    if isa(app.server,'tcpserver.internal.TCPServer') == 1 && (app.server.Connected)
                        if app.server.NumBytesAvailable > 0
                            ReadytoSend = TCPIP_readData(app);
                        end
                        if (ReadytoSend)
                            % 보낼 데이터 쓰는 함수.
                            TCPIP_writeData(app);
%                             ReadytoSend = 0;
                        end
                    end
                    
                    i = i+1;
                    
                    elapsedTime = toc;
                    if elapsedTime < 0.2
                        pause(0.2 - elapsedTime);                        
                    end
                    clearvars elapsedTime;
                    toc                    
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.EnableCheckBox.Value == true
                % 버튼 활성화
                app.RunButton.Enable = 'on';
                app.TrackingRunButton.Enable = 'on';
                app.RAEditField.Enable = 'on';
                app.DECEditField.Enable = 'on';
                app.ROTEditField.Enable = 'on';
                app.FoVxEditField.Enable = 'on';
                app.FoVyEditField.Enable = 'on';
                app.MagLimitEditField.Enable = 'on';
                app.Slider.Enable = 'on';
                
%                 app.UIAxes.Visible = 'on';
                % UIAxes의 마우스 상호작용 실행
%                 enableDefaultInteractivity(app.UIAxes);
                
                % 버튼 비활성화
                app.StopButton.Enable = 'off';

                app.TrackingFlag = 0;

            end
        end

        % Close request function: StarmapSIMUIFigure
        function StarmapSIMUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: ReadIMGButton
        function ReadIMGButtonPushed(app, event)
              if app.EnableCheckBox_2.Value == true
                % 파일을 읽어 중심좌표 읽어오기
                
                %Parameter Limit Control
                LimitControl(app);            
                % Set Parameters
                app.Params = SetParam(app);
                
                [IMG] = ReadIMGFile(app.IMGPath);
                [IMG] = ImgProcessing(IMG,app.Camera);               
                
                I = imshow(IMG, 'parent', app.UIAxes);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                
                app.StarImage = IMG;
                app.StarImageFlag = 1;              
                
                
                [Ys, Xs] = size(IMG);
                
                app.pixY = Ys;
                app.pixX = Xs;
                app.PixXxPixYEditField.Value = Xs;
                app.PixYEditField.Value = Ys;
                app.FoVxEditField.Value = 17.2;                
                app.FoVyEditField.Value = 12.94;
%                 app.MagLimitEditField.Value = 5.5;
% =========================================================================
% TCP/IP 서버가 열려있고, client 와 연결되어 있으면, 보낼 데이터를 write 한다.
            if isa(app.server,'tcpserver.internal.TCPServer') == 1
                if (app.server.Connected)
                    % 보낼 데이터 쓰는 함수.(픽셀수 세는 것으로 함수 다시 만들어야함)
                    TCPIP_writeData2(app, IMG);                     
                end
            end
              
            end
        end

        % Value changed function: DirEditField
        function DirEditFieldValueChanged(app, event)
            value = app.DirEditField.Value;
            app.TrackingPath = value;
        end

        % Value changed function: DirEditField_2
        function DirEditField_2ValueChanged(app, event)
            value = app.DirEditField_2.Value;
            app.IMGPath = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.StarmapSIMUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {700, 700, 700};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {700, 700};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {341, '1x', 294};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StarmapSIMUIFigure = uifigure('Visible', 'off');
            app.StarmapSIMUIFigure.Name = 'StarmapSIM';
            app.StarmapSIMUIFigure.Icon = 'Icon1.png';
            app.StarmapSIMUIFigure.CloseRequestFcn = createCallbackFcn(app, @StarmapSIMUIFigureCloseRequest, true);
            app.StarmapSIMUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.StarmapSIMUIFigure.KeyPressFcn = createCallbackFcn(app, @StarmapSIMUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.StarmapSIMUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.BlurTab = uitab(app.TabGroup);
            app.BlurTab.Title = 'Blur';
            app.TextArea_5 = uitextarea(app.BlurTab);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Gaussian Distribution'; 'H = K1 * exp(-K2 * StarMag + K3)'};
            app.ButtonGroup_Blur = uibuttongroup(app.BlurTab);
            app.ButtonGroup_Blur.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_BlurSelectionChanged, true);
            app.EnableButton_Blur = uiradiobutton(app.ButtonGroup_Blur);
            app.EnableButton_Blur.Text = 'Enable';
            app.EnableButton_Blur.Value = true;
            app.DisableButton_Blur = uiradiobutton(app.ButtonGroup_Blur);
            app.DisableButton_Blur.Text = 'Disable';
            app.TextArea_2 = uitextarea(app.BlurTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Gaussian Distribution'};
            app.SigmaEditField = uieditfield(app.BlurTab, 'numeric');
            app.SigmaEditField.Limits = [0 10];
            app.SigmaEditField.Value = 1;
            app.SigmaEditFieldLabel = uilabel(app.BlurTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.K1EditField = uieditfield(app.BlurTab, 'numeric');
            app.K1EditField.Limits = [-10000 10000];
            app.K1EditField.Value = 1500;
            app.K1EditFieldLabel = uilabel(app.BlurTab);
            app.K1EditFieldLabel.Text = 'K1';
            app.K2EditField = uieditfield(app.BlurTab, 'numeric');
            app.K2EditField.Limits = [-10 100];
            app.K2EditField.Value = 0.6;
            app.K2EditFieldLabel = uilabel(app.BlurTab);
            app.K2EditFieldLabel.Text = 'K2';
            app.K3EditField = uieditfield(app.BlurTab, 'numeric');
            app.K3EditField.Limits = [-100 100];
            app.K3EditField.Value = 1.2;
            app.K3EditFieldLabel = uilabel(app.BlurTab);
            app.K3EditFieldLabel.Text = 'K3';
            app.BackNoiseTab = uitab(app.TabGroup);
            app.BackNoiseTab.Title = 'BackNoise';
            app.GminEditFieldLabel = uilabel(app.BackNoiseTab);
            app.GminEditFieldLabel.Text = 'Gmin';
            app.GminEditField = uieditfield(app.BackNoiseTab, 'numeric');
            app.GminEditField.Limits = [0 255];
            app.GmaxEditFieldLabel = uilabel(app.BackNoiseTab);
            app.GmaxEditFieldLabel.Text = 'Gmax';
            app.GmaxEditField = uieditfield(app.BackNoiseTab, 'numeric');
            app.GmaxEditField.Limits = [0 255];
            app.GmaxEditField.Value = 1;
            app.TextArea = uitextarea(app.BackNoiseTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Backgound Noise'};
            app.ButtonGroup_Noise = uibuttongroup(app.BackNoiseTab);
            app.EnableButton_Noise = uiradiobutton(app.ButtonGroup_Noise);
            app.EnableButton_Noise.Text = 'Enable';
            app.DisableButton_Noise = uiradiobutton(app.ButtonGroup_Noise);
            app.DisableButton_Noise.Text = 'Disable';
            app.DisableButton_Noise.Value = true;
            app.MissingStarTab = uitab(app.TabGroup);
            app.MissingStarTab.Title = 'MissingStar';
            app.TextArea_4 = uitextarea(app.MissingStarTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Random Missing Stars'};
            app.ButtonGroup_Missing = uibuttongroup(app.MissingStarTab);
            app.EnableButton_Missing = uiradiobutton(app.ButtonGroup_Missing);
            app.EnableButton_Missing.Text = 'Enable';
            app.DisableButton_Missing = uiradiobutton(app.ButtonGroup_Missing);
            app.DisableButton_Missing.Text = 'Disable';
            app.DisableButton_Missing.Value = true;
            app.MissingNumMinEditFieldLabel = uilabel(app.MissingStarTab);
            app.MissingNumMinEditFieldLabel.Text = 'Missing Num Min';
            app.MissingNumMinEditField = uieditfield(app.MissingStarTab, 'numeric');
            app.MissingNumMinEditField.Limits = [1 9999];
            app.MissingNumMinEditField.Value = 1;
            app.MissingNumMaxEditFieldLabel = uilabel(app.MissingStarTab);
            app.MissingNumMaxEditFieldLabel.Text = 'Missing Num Max';
            app.MissingNumMaxEditField = uieditfield(app.MissingStarTab, 'numeric');
            app.MissingNumMaxEditField.Limits = [1 9999];
            app.MissingNumMaxEditField.Value = 1;
            app.RandomObjTab = uitab(app.TabGroup);
            app.RandomObjTab.Title = 'RandomObj';
            app.TextArea_3 = uitextarea(app.RandomObjTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Random Objects'};
            app.ButtonGroup_RandObj = uibuttongroup(app.RandomObjTab);
            app.EnableButton_RandObj = uiradiobutton(app.ButtonGroup_RandObj);
            app.EnableButton_RandObj.Text = 'Enable';
            app.DisableButton_RandObj = uiradiobutton(app.ButtonGroup_RandObj);
            app.DisableButton_RandObj.Text = 'Disable';
            app.DisableButton_RandObj.Value = true;
            app.ObjNumMinEditFieldLabel = uilabel(app.RandomObjTab);
            app.ObjNumMinEditFieldLabel.Text = 'Obj Num Min';
            app.ObjNumMinEditField = uieditfield(app.RandomObjTab, 'numeric');
            app.ObjNumMinEditField.Limits = [0 100];
            app.ObjNumMinEditField.Value = 1;
            app.ObjRadiusMinEditFieldLabel = uilabel(app.RandomObjTab);
            app.ObjRadiusMinEditFieldLabel.Text = 'Obj Radius Min';
            app.ObjRadiusMinEditField = uieditfield(app.RandomObjTab, 'numeric');
            app.ObjRadiusMinEditField.Limits = [0 100];
            app.ObjRadiusMinEditField.Value = 1;
            app.ObjRadiusMaxEditFieldLabel = uilabel(app.RandomObjTab);
            app.ObjRadiusMaxEditFieldLabel.Text = 'Obj Radius Max';
            app.ObjRadiusMaxEditField = uieditfield(app.RandomObjTab, 'numeric');
            app.ObjRadiusMaxEditField.Limits = [0 100];
            app.ObjRadiusMaxEditField.Value = 1;
            app.ObjNumMaxEditField_2Label = uilabel(app.RandomObjTab);
            app.ObjNumMaxEditField_2Label.Text = 'Obj Num Max';
            app.ObjNumMaxEditField = uieditfield(app.RandomObjTab, 'numeric');
            app.ObjNumMaxEditField.Limits = [0 100];
            app.ObjNumMaxEditField.Value = 3;
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.TrackingRunButton = uibutton(app.TrackingTab, 'push');
            app.TrackingRunButton.ButtonPushedFcn = createCallbackFcn(app, @TrackingRunButtonPushed, true);
            app.TrackingRunButton.Text = 'TrackingRun';
            app.StopButton = uibutton(app.TrackingTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.TextArea_6 = uitextarea(app.TrackingTab);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'Tracking Mode'};
            app.EnableCheckBox = uicheckbox(app.TrackingTab);
            app.EnableCheckBox.Text = 'Enable';
            app.DirEditFieldLabel = uilabel(app.TrackingTab);
            app.DirEditFieldLabel.Text = 'Dir';
            app.DirEditField = uieditfield(app.TrackingTab, 'text');
            app.DirEditField.ValueChangedFcn = createCallbackFcn(app, @DirEditFieldValueChanged, true);
            app.ReadIMGTab = uitab(app.TabGroup);
            app.ReadIMGTab.Title = 'ReadIMG';
            app.DirEditField_2Label = uilabel(app.ReadIMGTab);
            app.DirEditField_2Label.Text = 'Dir';
            app.DirEditField_2 = uieditfield(app.ReadIMGTab, 'text');
            app.DirEditField_2.ValueChangedFcn = createCallbackFcn(app, @DirEditField_2ValueChanged, true);
            app.TextArea_7 = uitextarea(app.ReadIMGTab);
            app.TextArea_7.Editable = 'off';
            app.TextArea_7.Value = {'Read IMG Mode'};
            app.EnableCheckBox_2 = uicheckbox(app.ReadIMGTab);
            app.EnableCheckBox_2.Text = 'Enable';
            app.ReadIMGButton = uibutton(app.ReadIMGTab, 'push');
            app.ReadIMGButton.ButtonPushedFcn = createCallbackFcn(app, @ReadIMGButtonPushed, true);
            app.ReadIMGButton.Text = 'Read IMG';
            app.RunButton = uibutton(app.GridLayout3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SaveimgButton = uibutton(app.GridLayout3, 'push');
            app.SaveimgButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimgButtonPushed, true);
            app.SaveimgButton.Text = 'Save img';
            app.RAEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RAEditField.Limits = [0 360];
            app.RAEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RAEditField.Value = 85;
            app.RAEditFieldLabel = uilabel(app.GridLayout3);
            app.RAEditFieldLabel.Text = 'RA';
            app.SavedirEditField = uieditfield(app.GridLayout3, 'text');
            app.SavedirEditField.ValueChangedFcn = createCallbackFcn(app, @SavedirEditFieldValueChanged, true);
            app.SavedirEditField.Value = './';
            app.SavedirEditFieldLabel = uilabel(app.GridLayout3);
            app.SavedirEditFieldLabel.Text = 'Save dir';
            app.ImagesizeDropDown = uidropdown(app.GridLayout3);
            app.ImagesizeDropDown.Items = {' 512x 512 pix', '1024x1024 pix', '2048x2048 pix', '4096x4096 pix'};
            app.ImagesizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagesizeDropDownValueChanged, true);
            app.ImagesizeDropDown.Value = '1024x1024 pix';
            app.ImagesizeDropDownLabel = uilabel(app.GridLayout3);
            app.ImagesizeDropDownLabel.Text = 'Image size';
            app.DECEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DECEditField.Limits = [-90 90];
            app.DECEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.DECEditFieldLabel = uilabel(app.GridLayout3);
            app.DECEditFieldLabel.Text = 'DEC';
            app.ROTEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ROTEditField.Limits = [0 360];
            app.ROTEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.ROTEditFieldLabel = uilabel(app.GridLayout3);
            app.ROTEditFieldLabel.Text = 'ROT';
            app.FoVxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FoVxEditField.Limits = [1 180];
            app.FoVxEditField.ValueChangedFcn = createCallbackFcn(app, @PixXxPixYEditFieldValueChanged, true);
            app.FoVxEditField.Value = 15;
            app.FoVxEditFieldLabel = uilabel(app.GridLayout3);
            app.FoVxEditFieldLabel.Text = 'FoVx';
            app.FoVyEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FoVyEditField.Limits = [1 180];
            app.FoVyEditField.ValueChangedFcn = createCallbackFcn(app, @PixXxPixYEditFieldValueChanged, true);
            app.FoVyEditField.Value = 15;
            app.FoVyEditFieldLabel = uilabel(app.GridLayout3);
            app.FoVyEditFieldLabel.Text = 'FoVy';
            app.MagLimitEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MagLimitEditField.Limits = [-100 100];
            app.MagLimitEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.MagLimitEditField.Value = 5;
            app.MagLimitEditFieldLabel = uilabel(app.GridLayout3);
            app.MagLimitEditFieldLabel.Text = 'MagLimit';
            app.Slider = uislider(app.GridLayout3);
            app.Slider.Limits = [0 360];
            app.Slider.MajorTicks = [0 90 180 270 360];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.ServerONButton = uibutton(app.GridLayout3, 'push');
            app.ServerONButton.ButtonPushedFcn = createCallbackFcn(app, @ServerONButtonPushed, true);
            app.ServerONButton.Text = 'Server ON';
            app.ServerLamp = uilamp(app.GridLayout3);
            app.ConnectLamp = uilamp(app.GridLayout3);
            app.ServerText = uitextarea(app.GridLayout3);
            app.ServerText.Editable = 'off';
            app.ImageSizeSelectOption = uibuttongroup(app.GridLayout3);
            app.ImageSizeSelectOption.SelectionChangedFcn = createCallbackFcn(app, @ImageSizeSelectOptionSelectionChanged, true);
            app.SelectImageSize = uiradiobutton(app.ImageSizeSelectOption);
            app.SelectImageSize.Text = '';
            app.SelectImageSize.Value = true;
            app.TypeImageSize = uiradiobutton(app.ImageSizeSelectOption);
            app.TypeImageSize.Text = '';
            app.PixXxPixYEditFieldLabel = uilabel(app.GridLayout3);
            app.PixXxPixYEditFieldLabel.Text = 'PixX x PixY';
            app.PixXxPixYEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PixXxPixYEditField.ValueChangedFcn = createCallbackFcn(app, @PixXxPixYEditFieldValueChanged, true);
            app.PixXxPixYEditField.Value = 1024;
            app.PixYEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PixYEditField.Editable = 'off';
            app.PixYEditField.Value = 1024;
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.StatusTextArea = uitextarea(app.GridLayout2);
            app.StatusTextArea.Editable = 'off';
            app.StatusTextAreaLabel = uilabel(app.GridLayout2);
            app.StatusTextAreaLabel.Text = 'Status';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.ClearLogButton = uibutton(app.GridLayout4, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.SaveLogButton = uibutton(app.GridLayout4, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLogButton.Text = 'Save Log';
            app.LogTextArea = uitextarea(app.GridLayout4);
            app.LogTextArea.Editable = 'off';
            app.LogTextAreaLabel = uilabel(app.GridLayout4);
            app.LogTextAreaLabel.Text = 'Log';
            app.Menu = uimenu(app.StarmapSIMUIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu없음';
            % Show the figure after all components are created
            app.StarmapSIMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StarmapSimGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StarmapSIMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StarmapSIMUIFigure)
        end
    end
end